classdef Hybrid_control_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ConnectButton                 matlab.ui.control.Button
        ScanButton                    matlab.ui.control.Button
        BatteryChargeDeltakilocoulombsGauge  matlab.ui.control.SemicircularGauge
        BatteryChargeDeltakilocoulombsGaugeLabel  matlab.ui.control.Label
        MATLABArduinoApplicationuptoDateCheckBox  matlab.ui.control.CheckBox
        ElectricalInspectionConductedCheckBox  matlab.ui.control.CheckBox
        PerimeterEstablishedCheckBox  matlab.ui.control.CheckBox
        DebrisClearedCheckBox         matlab.ui.control.CheckBox
        EntryExitsClearCheckBox       matlab.ui.control.CheckBox
        SerialPortDropDown            matlab.ui.control.DropDown
        SerialPortDropDownLabel       matlab.ui.control.Label
        NALabel                       matlab.ui.control.Label
        MessageLabel                  matlab.ui.control.Label
        EGTCGauge                     matlab.ui.control.SemicircularGauge
        EGTCGaugeLabel                matlab.ui.control.Label
        TSRPMX1000Gauge               matlab.ui.control.SemicircularGauge
        TSRPMX1000GaugeLabel          matlab.ui.control.Label
        EgRPMX1000Gauge               matlab.ui.control.SemicircularGauge
        EgRPMX1000GaugeLabel          matlab.ui.control.Label
        noerrorsLabel_2               matlab.ui.control.Label
        Eg_ErrorLabel                 matlab.ui.control.Label
        noerrorsLabel                 matlab.ui.control.Label
        El_ErrorLabel                 matlab.ui.control.Label
        SLabel                        matlab.ui.control.Label
        RunTimeLabel                  matlab.ui.control.Label
        OffLabel                      matlab.ui.control.Label
        StateLabel                    matlab.ui.control.Label
        VoltageGauge                  matlab.ui.control.SemicircularGauge
        VoltageGaugeLabel             matlab.ui.control.Label
        ThrottleGauge                 matlab.ui.control.SemicircularGauge
        ThrottleGaugeLabel            matlab.ui.control.Label
        StartStopButton               matlab.ui.control.StateButton
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        t = [];
        I_b = 0;
        kcoul = 0;
        MHS;
        log;
        sample = 2;
        lastState = "off";
        msgLength = 22;
        plotTimer;
        streamStart = 0;
        pipeSound;
        Fs;
        bingSound;
        Fs2;
        
    end
    
    methods (Access = private)
        
        function serialData = serialReceiveFn(app)
            if app.streamStart ~= 1
                app.streamStart = 1;
            end
            data = read(app.MHS, app.msgLength, "uint8");
            serialData = data(2:app.msgLength - 1);
            if data(1) == 193
                if data(app.msgLength) == 195
                    runTime = data(2) + bitshift(data(3),8);
                    eg_rpm = data(6) + bitshift(data(7),8);
                    ts_rpm = data(8);
                    egt = data(9) + bitshift(data(10),8);

                    app.log(app.sample, 1) = runTime;
                    app.log(app.sample, 2) = eg_rpm;
                    app.log(app.sample, 3) = ts_rpm;
                    app.log(app.sample, 4) = egt;
                    
                    if data(11) == 0
                        state = "off";
                        if app.lastState == "stopping" || app.lastState == "on"
                            app.NALabel.Text = "Stop request sucessfully executed...";
                        end
                    end
                    if data(11) == 1
                        state = "on";
                        if app.StartStopButton.Value == 0
                            write(app.MHS, [0 0 13], "uint8");
                            app.NALabel.Text = "Stop request not processed, resending command...";
                        end
                    end
                    if data(11) == 2
                        state = "starting";
                        if app.StartStopButton.Value == 0
                            write(app.MHS, [0 0 13], "uint8");
                            app.NALabel.Text = "Stop request not processed, resending command...";
                        end
                    end
                    if data(11) == 3
                        state = "stopping";
                        if app.lastState == "on" || app.lastState == "starting"
                            app.NALabel.Text = "Stop request processed, stopping...";
                        end
                    end

                    app.lastState = state;

                    app.log(app.sample, 5) = data(11);
       
               
                    if data(12) == 0
                        error_msg = "no electronics errors";
                    end
                    if data(12) == 1
                        error_msg = "error: voltage error";
                    end

                    app.log(app.sample, 6) = data(12);

                    if data(13) == 0
                        eg_error_msg = "no engine errors";
                    end
                    if data(13) == 1
                        eg_error_msg = "glow plug bad";
                    end
                    if data(13) == 2
                        eg_error_msg = "bad starter motor";
                    end
                    if data(13) == 3
                        eg_error_msg = "low RPM";
                    end
                    if data(13) == 4
                        eg_error_msg = "high EGT";
                    end
                    if data(13) == 5
                        eg_error_msg = "flame out";
                    end

                    app.log(app.sample, 7) = data(13);
                    app.log(app.sample, 8) = data(4);
                    app.log(app.sample, 9) = data(5);
                    if data(14) + bitshift(data(15), 8) > 32767
                        PID_err = data(14) + bitshift(data(15), 8) - 65536;
                    else
                        PID_err = data(14) + bitshift(data(15), 8);
                    end
                    if data(16) + bitshift(data(17),8) > 32767
                        P = data(16) + bitshift(data(17),8) - 65536;
                    else
                        P = data(16) + bitshift(data(17),8);
                    end
                    if data(18) + bitshift(data(19),8) > 32767
                        I = data(18) + bitshift(data(19),8) - 65536;
                    else
                        I = data(18) + bitshift(data(19),8);
                    end
                    if data(20) + bitshift(data(21),8) > 32767
                        D = data(20) + bitshift(data(21),8) - 65536;
                    else
                        D = data(20) + bitshift(data(21),8);
                    end

                    app.log(app.sample, 10) = PID_err;
                    app.log(app.sample, 11) = P;
                    app.log(app.sample, 12) = I;
                    app.log(app.sample, 13) = D;

            
                    app.ThrottleGauge.Value = data(4);
                    app.VoltageGauge.Value = data(5);
                    app.OffLabel.Text = state;
                    app.SLabel.Text = string(runTime);
                    app.noerrorsLabel.Text = error_msg;
                    app.noerrorsLabel_2.Text = eg_error_msg;
                    app.EgRPMX1000Gauge.Value = eg_rpm;
                    app.TSRPMX1000Gauge.Value = ts_rpm;
                    app.EGTCGauge.Value = egt;

                    app.kcoul = app.kcoul + toc*0.001*0.080645*app.log(app.sample - 1, 10);
                    app.log(app.sample, 14) = app.kcoul;
                    app.BatteryChargeDeltakilocoulombsGauge.Value = app.kcoul;

                    app.sample = app.sample + 1;
                    tic;
                end
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SerialPortDropDown.Items = serialportlist("available");
            [app.pipeSound, app.Fs] = audioread('metalPipe.mp3');
            [app.bingSound, app.Fs2] = audioread('bing-chilling_fcdGgUc.mp3');
        end

        % Value changed function: StartStopButton
        function StartStopButtonValueChanged(app, event)
            %sound(app.pipeSound, app.Fs);
            value = app.StartStopButton.Value;
            if value == 1
                write(app.MHS, [0 1 13], "uint8");
                app.StartStopButton.Text = "Stop";
            elseif value == 0
                write(app.MHS, [0 0 13], "uint8");
                app.StartStopButton.Text = "Start";
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.log)
                i = 0;
                while exist(strcat("log",string(i),".csv"),"file")
                    i = i + 1;
                end
                writematrix(app.log,strcat("log",string(i),".csv"));
                disp(strcat("Data written to ",strcat("log",string(i),".csv")));
            else
                disp("No data written...");
            end
            delete(app);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            %sound(app.pipeSound, app.Fs);
            if ~isempty(app.SerialPortDropDown.Value)
                if app.ElectricalInspectionConductedCheckBox.Value == 1 && app.DebrisClearedCheckBox.Value == 1 && app.EntryExitsClearCheckBox.Value == 1 && app.MATLABArduinoApplicationuptoDateCheckBox.Value == 1 && app.PerimeterEstablishedCheckBox.Value == 1
                    app.MHS = serialport(app.SerialPortDropDown.Value, 9600);
                    app.StartStopButton.Enable = "on";
                    configureCallback(app.MHS, "byte", app.msgLength, @(varargin)app.serialReceiveFn());
                else
                    app.NALabel.Text = "Pretest checklist incomplete...";
                end
            else
                app.NALabel.Text = "No serial port selected...";
            end
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            %sound(app.pipeSound, app.Fs);
            app.SerialPortDropDown.Items = serialportlist("available");
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            sound(app.bingSound, app.Fs2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Windows', 'Users', 'donsh', 'OneDrive - Embry-Riddle Aeronautical University', 'Peregrine Logo.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.AltText = 'Phillip';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'phillip.png');
            app.StartStopButton = uibutton(app.UIFigure, 'state');
            app.StartStopButton.ValueChangedFcn = createCallbackFcn(app, @StartStopButtonValueChanged, true);
            app.StartStopButton.Enable = 'off';
            app.StartStopButton.Text = 'Start/Stop';
            app.ThrottleGaugeLabel = uilabel(app.UIFigure);
            app.ThrottleGaugeLabel.Text = 'Throttle';
            app.ThrottleGauge = uigauge(app.UIFigure, 'semicircular');
            app.VoltageGaugeLabel = uilabel(app.UIFigure);
            app.VoltageGaugeLabel.Text = 'Voltage';
            app.VoltageGauge = uigauge(app.UIFigure, 'semicircular');
            app.VoltageGauge.Limits = [0 75];
            app.StateLabel = uilabel(app.UIFigure);
            app.StateLabel.Text = 'State:';
            app.OffLabel = uilabel(app.UIFigure);
            app.OffLabel.Text = 'Off';
            app.RunTimeLabel = uilabel(app.UIFigure);
            app.RunTimeLabel.Text = 'Run Time:';
            app.SLabel = uilabel(app.UIFigure);
            app.SLabel.Text = '0 S';
            app.El_ErrorLabel = uilabel(app.UIFigure);
            app.El_ErrorLabel.Text = 'El_Error:';
            app.noerrorsLabel = uilabel(app.UIFigure);
            app.noerrorsLabel.Text = 'no errors';
            app.Eg_ErrorLabel = uilabel(app.UIFigure);
            app.Eg_ErrorLabel.Text = 'Eg_Error:';
            app.noerrorsLabel_2 = uilabel(app.UIFigure);
            app.noerrorsLabel_2.Text = 'no errors';
            app.EgRPMX1000GaugeLabel = uilabel(app.UIFigure);
            app.EgRPMX1000GaugeLabel.Text = 'Eg RPM X 1000';
            app.EgRPMX1000Gauge = uigauge(app.UIFigure, 'semicircular');
            app.EgRPMX1000Gauge.Limits = [0 200];
            app.TSRPMX1000GaugeLabel = uilabel(app.UIFigure);
            app.TSRPMX1000GaugeLabel.Text = 'TS RPM X 1000';
            app.TSRPMX1000Gauge = uigauge(app.UIFigure, 'semicircular');
            app.TSRPMX1000Gauge.Limits = [0 10];
            app.EGTCGaugeLabel = uilabel(app.UIFigure);
            app.EGTCGaugeLabel.Text = 'EGT C';
            app.EGTCGauge = uigauge(app.UIFigure, 'semicircular');
            app.EGTCGauge.Limits = [0 1000];
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = 'Message:';
            app.NALabel = uilabel(app.UIFigure);
            app.NALabel.Text = 'N/A';
            app.SerialPortDropDownLabel = uilabel(app.UIFigure);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.UIFigure);
            app.SerialPortDropDown.Items = {''};
            app.SerialPortDropDown.Value = '';
            app.EntryExitsClearCheckBox = uicheckbox(app.UIFigure);
            app.EntryExitsClearCheckBox.Text = 'Entry/Exits Clear';
            app.DebrisClearedCheckBox = uicheckbox(app.UIFigure);
            app.DebrisClearedCheckBox.Text = 'Debris Cleared';
            app.PerimeterEstablishedCheckBox = uicheckbox(app.UIFigure);
            app.PerimeterEstablishedCheckBox.Text = 'Perimeter Established';
            app.ElectricalInspectionConductedCheckBox = uicheckbox(app.UIFigure);
            app.ElectricalInspectionConductedCheckBox.Text = 'Electrical Inspection Conducted';
            app.MATLABArduinoApplicationuptoDateCheckBox = uicheckbox(app.UIFigure);
            app.MATLABArduinoApplicationuptoDateCheckBox.Text = 'MATLAB/Arduino Application up to Date';
            app.BatteryChargeDeltakilocoulombsGaugeLabel = uilabel(app.UIFigure);
            app.BatteryChargeDeltakilocoulombsGaugeLabel.Text = 'Battery Charge Delta (kilocoulombs)';
            app.BatteryChargeDeltakilocoulombsGauge = uigauge(app.UIFigure, 'semicircular');
            app.BatteryChargeDeltakilocoulombsGauge.Limits = [-0.5 0.5];
            app.BatteryChargeDeltakilocoulombsGauge.MajorTicks = [-0.5 -0.3 -0.1 0.1 0.3 0.5];
            app.ScanButton = uibutton(app.UIFigure, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hybrid_control_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end