classdef XJEP_Control_Panel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        XJEPControlPanelUIFigure  matlab.ui.Figure
        Image2                    matlab.ui.control.Image
        Image                     matlab.ui.control.Image
        ABSwitch                  matlab.ui.control.ToggleSwitch
        ABSwitchLabel             matlab.ui.control.Label
        ABFuelPumpSwitch          matlab.ui.control.ToggleSwitch
        ABFuelPumpSwitchLabel     matlab.ui.control.Label
        MainFuelPumpSwitch        matlab.ui.control.ToggleSwitch
        MainFuelPumpSwitchLabel   matlab.ui.control.Label
        kRPMGauge                 matlab.ui.control.Gauge
        kRPMGaugeLabel            matlab.ui.control.Label
        EGTIndicator              Aero.ui.control.EGTIndicator
        EGTIndicatorLabel         matlab.ui.control.Label
        NASlider                  matlab.ui.control.Slider
        NASliderLabel             matlab.ui.control.Label
        EngineRPMSlider           matlab.ui.control.Slider
        EngineRPMSliderLabel      matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        NozzleWarning             matlab.ui.control.Lamp
        Label_2                   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        ABONLamp                  matlab.ui.control.Lamp
        ENGINEONLamp              matlab.ui.control.Lamp
        StopEngineButton          matlab.ui.control.Button
        StartEngineButton         matlab.ui.control.Button
    end


    properties (Access = private)
            a;
            h = [];
            stop;
            done = 0;
            NATimeSeries;
            engineThrottlePWM;
            CNServo;
            throttle = 0;
            CNArea = 100;
            CNServoAngle = 90;
            rpms = [33,105];
            thrtl = [0.314,1];
            serialTimer = timer('Name', 'Serial timer', 'Period', 0.5, 'ExecutionMode', 'fixedSpacing');
            PROInterface;
    end
    
    methods (Access = private)
        
        function serialTimerCallback(app, timer_obj, event)
            write(app.PROInterface,[1 44 'RAC' 44 2 13]);
            pause(0.2);
            numBytes = app.PROInterface.NumBytesAvailable;
            if numBytes > 0
                serialDataIn = read(app.PROInterface,numBytes);
                k = strfind(serialDataIn, 44);
                rpm = str2double(char(serialDataIn((k(5)+1):(k(6)-1))));
                egt = str2double(char(serialDataIn((k(6)+1):(k(7)-1))));
                fprintf("%g\n",rpm);
                app.EGTIndicator.Value = egt;
                app.kRPMGauge.Value = rpm/1000;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.a = arduino('COM5','Mega2560','ForceBuildOn', false);
            app.engineThrottlePWM  = servo(app.a,'D3', 'MinPulseDuration', 800e-6, 'MaxPulseDuration', 2200e-6);
            app.CNServo = servo(app.a,'D2');
            app.PROInterface = device(app.a,'SerialPort',2);
            app.serialTimer.TimerFcn = @(timer_obj, event) app.serialTimerCallback(timer_obj, event);
            app.serialTimer.start;
            app.ENGINEONLamp.Color = 'g';
            app.Label.Text = 'ENGINE OFF';
            if app.ABSwitch.Value == 1
                app.ABONLamp.Color = 'r';
                app.Label_2.Text = 'AB ON';
            else
                app.ABONLamp.Color = 'g';
                app.Label_2.Text = 'AB OFF';
            end
            app.ABSwitch.Enable = "off";
            app.NozzleWarning.Visible = "off";
            app.Label_3.Visible = "off";
            app.StartEngineButton.Enable = "off";
        end

        % Button pushed function: StartEngineButton
        function StartEngineButtonPushed(app, event)
            writePosition(app.engineThrottlePWM,0);
            pause(1);
            writePosition(app.engineThrottlePWM,0.5);
            pause(1);
            writePosition(app.engineThrottlePWM,1);
            pause(1);
            writePosition(app.engineThrottlePWM,0.5);
            app.ENGINEONLamp.Color = 'r';
            app.Label.Text = 'ENGINE ON';
            app.NozzleWarning.Visible = "on";
            app.Label_3.Visible = "on";
            if app.NASlider.Value > 60
                app.Label_3.Text = "WARNING: Nozzle Area Too Large";
                app.NozzleWarning.Color = 'y';
            else
                app.Label_3.Text = "Nozzle Area OK";
                app.NozzleWarning.Color = 'g';
            end
            
        end

        % Button pushed function: StopEngineButton
        function StopEngineButtonPushed(app, event)
            %writePosition(app.engineThrottlePWM,0);
            app.ENGINEONLamp.Color = 'g';
            app.Label.Text = 'ENGINE OFF';
            app.Label_3.Visible = "off";
            app.NozzleWarning.Visible = "off";
        end

        % Value changed function: EngineRPMSlider
        function EngineRPMSliderValueChanged(app, event)
            value = app.EngineRPMSlider.Value;
            app.throttle = interp1(app.rpms,app.thrtl,value);
            writePosition(app.engineThrottlePWM,app.throttle);
        end

        % Value changed function: NASlider
        function NASliderValueChanged(app, event)
            value = app.NASlider.Value;
            app.CNServoAngle = interp1([0,100],[0.4,0.75],value);
            writePosition(app.CNServo,app.CNServoAngle);
            if app.ABSwitch ~= 1
                if app.NASlider.Value > 60
                    app.Label_3.Text = "WARNING: Nozzle Area Too Large";
                    app.NozzleWarning.Color = 'y';
                else
                    app.Label_3.Text = "Nozzle Area OK";
                    app.NozzleWarning.Color = 'g';
                end
            else
                if app.NASlider.Value < 50
                    app.Label_3.Text = "WARNING: Nozzle Area Too Small";
                    app.NozzleWarning.Color = 'y';
                else
                    app.Label_3.Text = "Nozzle Area OK";
                    app.NozzleWarning.Color = 'g';
                end
            end
        end

        % Value changed function: ABSwitch
        function ABSwitchValueChanged(app, event)
            value = app.ABSwitch.Value;
            fprintf('%g\n',value);
            if value == 1
                app.ABONLamp.Color = 'r';
                app.Label_2.Text = 'AB ON';
            else
                app.ABONLamp.Color = 'g';
                app.Label_2.Text = 'AB OFF';
            end
            if app.ABSwitch.Value ~= 1
                if app.NASlider.Value > 60
                    app.Label_3.Text = "WARNING: Nozzle Area Too Large";
                    app.NozzleWarning.Color = 'y';
                else
                    app.Label_3.Text = "Nozzle Area OK";
                    app.NozzleWarning.Color = 'g';
                end
            else
                if app.NASlider.Value < 50
                    app.Label_3.Text = "WARNING: Nozzle Area Too Small";
                    app.NozzleWarning.Color = 'y';
                else
                    app.Label_3.Text = "Nozzle Area OK";
                    app.NozzleWarning.Color = 'g';
                end
            end
        end

        % Value changed function: ABFuelPumpSwitch
        function ABFuelPumpSwitchValueChanged(app, event)
            value = app.ABFuelPumpSwitch.Value;
            if value == 1
                app.ABSwitch.Enable = "on";
            else
                app.ABSwitch.Enable = "off";
            end
        end

        % Value changed function: MainFuelPumpSwitch
        function MainFuelPumpSwitchValueChanged(app, event)
            value = app.MainFuelPumpSwitch.Value;
            if value == 1
                app.StartEngineButton.Enable = "on";
            else
                app.StartEngineButton.Enable = "off";
            end
        end

        % Callback function: not associated with a component
        function RecordDataButtonPushed(app, event)
            startTime = datetime('now');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.XJEPControlPanelUIFigure = uifigure('Visible', 'off');
            app.XJEPControlPanelUIFigure.Name = 'XJEP Control Panel';
            app.XJEPControlPanelUIFigure.Icon = fullfile(pathToMLAPP, 'applogo.png');
            app.StartEngineButton = uibutton(app.XJEPControlPanelUIFigure, 'push');
            app.StartEngineButton.ButtonPushedFcn = createCallbackFcn(app, @StartEngineButtonPushed, true);
            app.StartEngineButton.Enable = 'off';
            app.StartEngineButton.Text = 'Start Engine';
            app.StopEngineButton = uibutton(app.XJEPControlPanelUIFigure, 'push');
            app.StopEngineButton.ButtonPushedFcn = createCallbackFcn(app, @StopEngineButtonPushed, true);
            app.StopEngineButton.Text = 'Stop Engine';
            app.ENGINEONLamp = uilamp(app.XJEPControlPanelUIFigure);
            app.ABONLamp = uilamp(app.XJEPControlPanelUIFigure);
            app.Label = uilabel(app.XJEPControlPanelUIFigure);
            app.Label_2 = uilabel(app.XJEPControlPanelUIFigure);
            app.NozzleWarning = uilamp(app.XJEPControlPanelUIFigure);
            app.Label_3 = uilabel(app.XJEPControlPanelUIFigure);
            app.EngineRPMSliderLabel = uilabel(app.XJEPControlPanelUIFigure);
            app.EngineRPMSliderLabel.Text = 'Engine RPM';
            app.EngineRPMSlider = uislider(app.XJEPControlPanelUIFigure);
            app.EngineRPMSlider.Limits = [33 105];
            app.EngineRPMSlider.ValueChangedFcn = createCallbackFcn(app, @EngineRPMSliderValueChanged, true);
            app.EngineRPMSlider.Value = 33;
            app.NASliderLabel = uilabel(app.XJEPControlPanelUIFigure);
            app.NASliderLabel.Text = 'NA %';
            app.NASlider = uislider(app.XJEPControlPanelUIFigure);
            app.NASlider.ValueChangedFcn = createCallbackFcn(app, @NASliderValueChanged, true);
            app.EGTIndicatorLabel = uilabel(app.XJEPControlPanelUIFigure);
            app.EGTIndicatorLabel.Text = 'EGTIndicator';
            app.EGTIndicator = uiaeroegt(app.XJEPControlPanelUIFigure);
            app.EGTIndicator.Limits = [0 900];
            app.kRPMGaugeLabel = uilabel(app.XJEPControlPanelUIFigure);
            app.kRPMGaugeLabel.Text = 'kRPM';
            app.kRPMGauge = uigauge(app.XJEPControlPanelUIFigure, 'circular');
            app.kRPMGauge.Limits = [0 110];
            app.MainFuelPumpSwitchLabel = uilabel(app.XJEPControlPanelUIFigure);
            app.MainFuelPumpSwitchLabel.Text = 'Main Fuel Pump';
            app.MainFuelPumpSwitch = uiswitch(app.XJEPControlPanelUIFigure, 'toggle');
            app.MainFuelPumpSwitch.ItemsData = [0 1];
            app.MainFuelPumpSwitch.ValueChangedFcn = createCallbackFcn(app, @MainFuelPumpSwitchValueChanged, true);
            app.MainFuelPumpSwitch.Value = 0;
            app.ABFuelPumpSwitchLabel = uilabel(app.XJEPControlPanelUIFigure);
            app.ABFuelPumpSwitchLabel.Text = 'AB Fuel Pump';
            app.ABFuelPumpSwitch = uiswitch(app.XJEPControlPanelUIFigure, 'toggle');
            app.ABFuelPumpSwitch.ItemsData = [0 1];
            app.ABFuelPumpSwitch.ValueChangedFcn = createCallbackFcn(app, @ABFuelPumpSwitchValueChanged, true);
            app.ABFuelPumpSwitch.Value = 0;
            app.ABSwitchLabel = uilabel(app.XJEPControlPanelUIFigure);
            app.ABSwitchLabel.Text = 'AB';
            app.ABSwitch = uiswitch(app.XJEPControlPanelUIFigure, 'toggle');
            app.ABSwitch.ItemsData = [0 1];
            app.ABSwitch.ValueChangedFcn = createCallbackFcn(app, @ABSwitchValueChanged, true);
            app.ABSwitch.Value = 0;
            app.Image = uiimage(app.XJEPControlPanelUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IMG_3935.jpg');
            app.Image2 = uiimage(app.XJEPControlPanelUIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2024-03-22 150008.png');
            % Show the figure after all components are created
            app.XJEPControlPanelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = XJEP_Control_Panel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.XJEPControlPanelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.XJEPControlPanelUIFigure)
        end
    end
end